classdef AppWSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button_9        matlab.ui.control.Button
        Button_8        matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
        Button_6        matlab.ui.control.Button
        Button_5        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        FloorGauge      matlab.ui.control.Gauge
        TextArea        matlab.ui.control.TextArea
        TextAreaLabel   matlab.ui.control.Label
        Button          matlab.ui.control.Button
    end


    properties (Access = private)
        chart % Description
        CurFloor
    end

    methods (Access = public)

        function updateCounter(app, Cur_Floor)
            app.CurFloor = Cur_Floor(end);
            app.EditField.Value = string(app.CurFloor);
            app.FloorGauge.Value = Cur_Floor;
        end

        function NxtFloor(app,NxFloor)
            app.chart.Req_Floor = NxFloor;
        end

        function Floor_request(app, Req_Floor)
            NxtFloor(app,Req_Floor);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chart)
            app.chart = chart;               % access data in Stateflow chart
            assignin('base','app',app)      % access app from base workspaces
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea.Value = string(event.Source.Text);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            set_param('Simulink_Model','SimulationCommand', 'stop');
        end

        % Button pushed function: Button_2
        function One(app, event)
            %                         var = event.Source.Text;
            Floor_request(app, 1)
            %                app.FloorGauge.Value = 2;
            %             disp("hello")
            %             jub = fopen("jub.txt", 'w+');
            %             fprintf(jub, string(var) + '\n');
            %             fclose(jub);
        end

        % Callback function: Button_3, UIFigure
        function Two(app, event)
            Floor_request(app, 2)
        end

        % Button pushed function: Button_4
        function Three(app, event)
            Floor_request(app, 3)
        end

        % Button pushed function: Button_5
        function Four(app, event)
            Floor_request(app, 4)
        end

        % Button pushed function: Button_6
        function Five(app, event)
            Floor_request(app, 5)
        end

        % Button pushed function: Button_7
        function Six(app, event)
            Floor_request(app, 6)
        end

        % Button pushed function: Button_8
        function Seven(app, event)
            Floor_request(app, 7)

        end

        % Button pushed function: Button_9
        function Eight(app, event)
            Floor_request(app, 8)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @Two, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.FloorGauge = uigauge(app.UIFigure, 'circular');
            app.FloorGauge.Limits = [1 8];
            app.FloorGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.FloorGauge.MinorTicks = [];
            app.FloorGauge.Value = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @One, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Two, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Three, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Four, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Five, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Six, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Seven, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Eight, true);
            app.Button_9.Text = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppWSimulink(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end