classdef App_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        MOVINGButton     matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        FloorGauge       matlab.ui.control.Gauge
        FloorGaugeLabel  matlab.ui.control.Label
        Button_2         matlab.ui.control.Button
        Button_1         matlab.ui.control.Button
    end

    
    properties (Access = private)
        RqFloor = '';
    end
    
    methods (Access = private)
        
        function FloorReached(app)
            app.RqFloor = app.RqFloor(2:end);
            disp(app.RqFloor)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            floor = '1';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            floor = '2';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            floor = '3';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            floor = '4';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            floor = '5';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            floor = '6';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            floor = '7';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            floor = '8';
            app.RqFloor = append(app.RqFloor,floor);
            disp(app.RqFloor(end))
        end

        % Button pushed function: MOVINGButton
        function MOVINGButtonPushed(app, event)
            app.FloorReached()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.FloorGaugeLabel = uilabel(app.UIFigure);
            app.FloorGaugeLabel.Text = 'Floor';
            app.FloorGauge = uigauge(app.UIFigure, 'circular');
            app.FloorGauge.Limits = [1 8];
            app.FloorGauge.MajorTicks = [1 2 3 4 5 6 7 8];
            app.FloorGauge.MinorTicks = [];
            app.FloorGauge.Value = 1;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.MOVINGButton = uibutton(app.UIFigure, 'push');
            app.MOVINGButton.ButtonPushedFcn = createCallbackFcn(app, @MOVINGButtonPushed, true);
            app.MOVINGButton.Text = 'MOVING';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end